classdef DirLabelMapsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        LabelNumericEditField matlab.ui.control.Label            % sp2
        sp2Box                matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField        matlab.ui.control.Label            % Dataset ...
        DatasetName           matlab.ui.control.EditField       
        LabelDropDown         matlab.ui.control.Label            % Cropped ...
        DropDown              matlab.ui.control.DropDown         % RGB, SVD
        GO                    matlab.ui.control.Button           % GO!
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % GO button pushed function
        function GOButtonPushed(app)
            QcDir=uipickfiles('Output','cell','Prompt','Choose Directories to Process');
            mappath=uigetdir('C:\','Choose a place to save output');
            
            for i=1:length(QcDir)
                pos=strfind(QcDir{i},'\');
                sampleID{i}=sprintf('%s',QcDir{i}(pos(end)+1:length(QcDir{i}))); % grab folder name
            end
            %% Updated version of dirlabelmaps -- make output data structure, crop out RGB (or SVD) maps
            [DirLabelS]=DirLabelMapsStruct(QcDir,app.DatasetName.Value,0,mappath,app.sp2Box.Value,app.DropDown.Value)
            cd(mappath)
            vname=@(x)inputname(1);
            DirLabelS.sampleID=sampleID;
            DirLabelS.saveLoc=mappath;
            DirLabelS.FileName=app.DatasetName.Value;
            save(app.DatasetName.Value,vname(DirLabelS))
            return
        end

        % sp2Box value changed function
        function sp2BoxValueChanged(app)
            sp2 = app.sp2Box.Value;
            
        end

        % DatasetName value changed function
        function DatasetNameValueChanged(app)
            SampleID = app.DatasetName.Value;
            
        end

        % DropDown value changed function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'sp2';
            app.sp2Box = uieditfield(app.UIFigure, 'numeric');
            app.sp2Box.ValueChangedFcn = createCallbackFcn(app, @sp2BoxValueChanged);
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Dataset Name';
            app.DatasetName = uieditfield(app.UIFigure, 'text');
            app.DatasetName.ValueChangedFcn = createCallbackFcn(app, @DatasetNameValueChanged);
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Cropped Image Type';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'RGB', 'SVD'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'RGB';
            app.GO = uibutton(app.UIFigure, 'push');
            app.GO.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed);
            app.GO.Text = 'GO!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DirLabelMapsGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end