classdef ParticleAnalysis2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure          % ParticleAnalysis2
        RawDataProcessing     matlab.ui.container.Panel % Raw Data Processing
        ProcStackData         matlab.ui.control.Button  % Process Stack Data
        StackFromImage        matlab.ui.control.Button  % Stack from Images
        DeglitchStack         matlab.ui.control.Button  % DeglitchStack
        ProcessedDataAnalysis matlab.ui.container.Panel % Processed Data An...
        DirLabelStruct        matlab.ui.control.Button  % DirLabelStruct
        StackLab              matlab.ui.control.Button  % StackLab
        CarbonMaps            matlab.ui.control.Button  % CarbonMaps
        FullSpecCMaps         matlab.ui.control.Button  % FullSpecCMaps
        DatasetExploration    matlab.ui.container.Panel % Dataset Explorati...
        ExploreParticles      matlab.ui.control.Button  % ExploreParticles
        RemoveBadParticles    matlab.ui.control.Button  % Remove Bad Particles
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ProcStackData button pushed function
        function ProcStackDataButtonPushed(app)
            %RawStackDir=uigetdir
            %cd(RawStackDir)
            RawStackDir=uipickfiles('Output','cell','Prompt','Choose Directories to Process',...
                'NumFiles',[]);
            button = questdlg('suppress figures?','Raw Data Processing','yes','no','yes');
            switch button
                case 'yes'
                    plotfig=0;
                case 'no'
                    plotfig=1;
            end
            
            for i=1:length(RawStackDir)
                SingStackProcGUI(RawStackDir{i},plotfig)
            end
            
        end

        % StackFromImage button pushed function
        function StackFromImageButtonPushed(app)
            [FileNames,PathNames]=uigetfile({'*.xim;*.hdr', 'STXM Image Files (*.xim, *.hdr)'; ...
                '*.*','All Files (*.*)'},'Pick a file','MultiSelect', 'on');
            S = LoadImageRawGui(PathNames,FileNames)
            
        end

        % DirLabelStruct button pushed function
        function DirLabelStructButtonPushed(app)
            DirLabelMapsGUI
        end

        % ExploreParticles button pushed function
        function ExploreParticlesButtonPushed(app)
            [FileName,PathName]=uigetfile({'*.mat'},...
                'Pick a .mat file with the processed dataset','MultiSelect', 'off');
            cd(PathName);
            load(FileName)
            if isstr(DirLabelS.sampleID{1})
                [Selection,ok] = listdlg('ListString',DirLabelS.sampleID,'PromptString','choose a sample')
                for i=Selection
                    CropPartRGBPlot(DirLabelS,i)
                    %                     CropPartRGBPlotGUITest(DirLabelS,i);
                end
            else
                beep
                errordlg('This is not a dataset. You must use output file from DirLabelStruct')
            end
        end

        % StackLab button pushed function
        function RunStackLab(app)
            [FileName,PathName]=uigetfile({'*.mat'},...
                'Pick a .mat file with the processed data','MultiSelect', 'off');
            if PathName>0
                cd(PathName);
                load(FileName);
                STACKLab(Snew);
            else
                beep
                errordlg('You must select a .mat file with the processed stack data')
            end
        end

        % CarbonMaps button pushed function
        function CarbonMapsButtonPushed(app)
            [FileName,PathName]=uigetfile({'*.mat'},...
                'Pick a .mat file with the processed data','MultiSelect', 'off');
            if PathName>0
                cd(PathName);
                load(FileName);
                Snew=CarbonMaps(Snew);
            else
                beep
                errordlg('This is not a stack datafile. You must use output file from raw data processing routines')
            end
        end

        % FullSpecCMaps button pushed function
        function FullSpecCMapsButtonPushed(app)
            [FileName,PathName]=uigetfile({'*.mat'},...
                'Pick a .mat file with the processed data','MultiSelect', 'off');
            if PathName>0
                cd(PathName);
                load(FileName);
                Snew=Diffmaps(Snew);
                if ~isempty(Snew.DiffMap)
                    Snew=PartLabelCompSize(Snew);
                else
                    beep
                    errordlg('You do not have enough energies to run DiffMaps')
                end
            else
                beep
                errordlg('This is not a dataset. You must use output file from raw data processing routines')
            end
        end

        % RemoveBadParticles button pushed function
        function RemoveBadParticlesButtonPushed(app)
            [FileName,PathName]=uigetfile({'*.mat'},...
                'Pick a .mat file with the processed dataset','MultiSelect', 'off');
            cd(PathName);
            load(FileName)
            if isstr(DirLabelS.sampleID{1})
                DirLabelS=RemoveBadParticles(DirLabelS)
                h = msgbox({'Particles have been deleted',...
                    sprintf('New data file saved as %sQC.mat',FileName(1:end-4))}...
                    ,'Remove Bad Particles');
            else
                beep
                errordlg('This is not a dataset. You must use output file from DirLabelStruct')
            end
            
        end

        % DeglitchStack button pushed function
        function DeglitchStackButtonPushed(app)
            RawStackDir=uigetdir
            if isstr(RawStackDir)
                cd(RawStackDir)
                SingStackProcGUI(RawStackDir,1,1)
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ParticleAnalysis2';
            setAutoResize(app, app.UIFigure, true)
            app.RawDataProcessing = uipanel(app.UIFigure);
            app.RawDataProcessing.Title = 'Raw Data Processing';
            app.RawDataProcessing.Units = 'pixels';
            app.ProcStackData = uibutton(app.RawDataProcessing, 'push');
            app.ProcStackData.ButtonPushedFcn = createCallbackFcn(app, @ProcStackDataButtonPushed);
            app.ProcStackData.Text = 'Process Stack Data';
            app.StackFromImage = uibutton(app.RawDataProcessing, 'push');
            app.StackFromImage.ButtonPushedFcn = createCallbackFcn(app, @StackFromImageButtonPushed);
            app.StackFromImage.Text = 'Stack from Images';
            app.DeglitchStack = uibutton(app.RawDataProcessing, 'push');
            app.DeglitchStack.ButtonPushedFcn = createCallbackFcn(app, @DeglitchStackButtonPushed);
            app.DeglitchStack.Text = 'DeglitchStack';
            app.ProcessedDataAnalysis = uipanel(app.UIFigure);
            app.ProcessedDataAnalysis.Title = 'Processed Data Analysis';
            app.ProcessedDataAnalysis.Units = 'pixels';
            app.DirLabelStruct = uibutton(app.ProcessedDataAnalysis, 'push');
            app.DirLabelStruct.ButtonPushedFcn = createCallbackFcn(app, @DirLabelStructButtonPushed);
            app.DirLabelStruct.Text = 'DirLabelStruct';
            app.StackLab = uibutton(app.ProcessedDataAnalysis, 'push');
            app.StackLab.ButtonPushedFcn = createCallbackFcn(app, @RunStackLab);
            app.StackLab.Text = 'StackLab';
            app.CarbonMaps = uibutton(app.ProcessedDataAnalysis, 'push');
            app.CarbonMaps.ButtonPushedFcn = createCallbackFcn(app, @CarbonMapsButtonPushed);
            app.CarbonMaps.Text = 'CarbonMaps';
            app.FullSpecCMaps = uibutton(app.ProcessedDataAnalysis, 'push');
            app.FullSpecCMaps.ButtonPushedFcn = createCallbackFcn(app, @FullSpecCMapsButtonPushed);
            app.FullSpecCMaps.Text = 'FullSpecCMaps';
            app.DatasetExploration = uipanel(app.UIFigure);
            app.DatasetExploration.Title = 'Dataset Exploration and QC';
            app.DatasetExploration.Units = 'pixels';
            app.ExploreParticles = uibutton(app.DatasetExploration, 'push');
            app.ExploreParticles.ButtonPushedFcn = createCallbackFcn(app, @ExploreParticlesButtonPushed);
            app.ExploreParticles.Text = 'ExploreParticles';
            app.RemoveBadParticles = uibutton(app.DatasetExploration, 'push');
            app.RemoveBadParticles.ButtonPushedFcn = createCallbackFcn(app, @RemoveBadParticlesButtonPushed);
            app.RemoveBadParticles.Text = 'Remove Bad Particles';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ParticleAnalysis2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end